classdef Final_App_Bme201P < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChooseapresetfiltertoapplyLabel  matlab.ui.control.Label
        YoumayeditthesliderstotheattenuationyouwantLabel  matlab.ui.control.Label
        UploadingAudioRedPath12BlackPath3456Label  matlab.ui.control.Label
        HearingLossSimulationLabel      matlab.ui.control.Label
        TorecordaudiofollowBlueBlackPathandRedBlackPathtouploadoneLabel  matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        PreviewDurationsEditField       matlab.ui.control.NumericEditField
        PreviewDurationsEditFieldLabel  matlab.ui.control.Label
        PreviewFilteredAudioButton      matlab.ui.control.Button
        VedatCanDuzgezenLabel           matlab.ui.control.Label
        ZerosButton                     matlab.ui.control.Button
        CommandWindowLabel              matlab.ui.control.Label
        FilterSlidersforHearingLossLabel  matlab.ui.control.Label
        PlotFrequencyversusGainButton   matlab.ui.control.Button
        kHzSlider_6                     matlab.ui.control.Slider
        kHzSlider_6Label                matlab.ui.control.Label
        kHzSlider_5                     matlab.ui.control.Slider
        kHzSlider_5Label                matlab.ui.control.Label
        kHzSlider_4                     matlab.ui.control.Slider
        kHzSlider_4Label                matlab.ui.control.Label
        kHzSlider_3                     matlab.ui.control.Slider
        kHzSlider_3Label                matlab.ui.control.Label
        kHzSlider_2                     matlab.ui.control.Slider
        kHzSlider_2Label                matlab.ui.control.Label
        kHzSlider                       matlab.ui.control.Slider
        kHzSliderLabel                  matlab.ui.control.Label
        FilterstoApplyLabel             matlab.ui.control.Label
        MetabolicSensoryButton          matlab.ui.control.Button
        SensoryButton                   matlab.ui.control.Button
        MetabolicButton                 matlab.ui.control.Button
        PreMetabolicButton              matlab.ui.control.Button
        OlderNormalButton               matlab.ui.control.Button
        PlotAmplitudeversusTimeButton   matlab.ui.control.Button
        RecordingLamp                   matlab.ui.control.Lamp
        RecordingLampLabel              matlab.ui.control.Label
        RecondingLengthsEditField       matlab.ui.control.NumericEditField
        RecondingLengthsEditFieldLabel  matlab.ui.control.Label
        UploadAudioButton               matlab.ui.control.Button
        PreviewUploadButton             matlab.ui.control.Button
        RecordAudioButton               matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        recObj='' % Set a defined variable for the recorded audio.
        s         % Set a defined variable for the recording duration.
        Fs        % Set a defined variable for the sampling frequency.
        y         % Set a defined variable for the sampled data.
        Filtered_Audio % Set a defined variable for the filtered audio. 
        H         % Set a defined variable for the filter output.
        fH        % Set a defined variable for the filter output.
        d         % Varible for Audio Preview Duration
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)
            % Define Variables:
            
         fs = 44100;  % Set the sampling frequency.
            
         nc = 1;      % Set the number of channels.
            
         nb = 16;     % Set the number of bits.       
            
         app.recObj=audiorecorder ( fs , nb , nc); % Define rec.Obj
            ... with the assigned recording settings.
                        
            % Execute the Recording:
           
         record (app.recObj);              % Record a sound.
            
         app.RecordingLamp.Enable = 'on';  % Turn on the lamp to
            ... indicate that the recording is running.
            
         pause (app.s); % Pause the code to wait for the audio of 
            ... desired length to be fully recorded.
            
         app.RecordingLamp.Enable = 'off'; % Turn off the lamp to
            ... indicate that the recording ended.
            
         stop (app.recObj); % Stop the recording.
                    
            % Process the Data:
            
         Recording = getaudiodata (app.recObj); % Save the recording
            ... under the variable Recording.
            
         audiowrite ('recording.wav', Recording , fs); % Turn the
            ... audiorecorder file to a Wave file.
            
         clear app.y app.Fs % Clear any previously defined data and
            ... sampling frequency values.
            
         [app.y , app.Fs] = audioread ('recording.wav'); % Define
            ...data & sampling frequency values for the recorded file.
         
         play (app.recObj); % Play the preview of the recording.
            
         pause (app.s);   % Pause the code for the preview to finish.     
            
        end

        % Button pushed function: PreviewUploadButton
        function PreviewUploadButtonPushed(app, event)
            % Preview the Uploaded File:
            
         clear app.y app.Fs % Clear any previously defined data 
            ... and sampling frequency values. 
            
         [app.y , app.Fs] = audioread (app.recObj); % Define new
            ... data & sampling frequency for the uploaded file.
         
         x = app.y(1:app.d*app.Fs); % Define a vector for the desired
            ... length to be previewed.
            
         playblocking (audioplayer (x , app.Fs)) % Play the 
            ... preview of the uploaded file.
                
        end

        % Button pushed function: UploadAudioButton
        function UploadAudioButtonPushed(app, event)
            % Upload an Audio:
            
         app.recObj = uigetfile ('*.*'); % Pull up the
            ... file tab to select any Wave or MP3 files to upload.
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % Axes for Amplitude versus Time:
        end

        % Value changed function: RecondingLengthsEditField
        function RecondingLengthsEditFieldValueChanged(app, event)
            % Determine the Length of the Recording
            
         app.s = app.RecondingLengthsEditField.Value; % Enter a 
            ... value for the desired length of the recording.
            
        end

        % Button pushed function: PlotAmplitudeversusTimeButton
        function PlotAmplitudeversusTimeButtonPushed(app, event)
            % Plot a Graph:
            
         t = linspace (0 , length (app.y) / app.Fs , length(app.y));
            % Define a time vector.
            
         plot (app.UIAxes , t , app.y) % Plot the graph.
         
        end

        % Button pushed function: OlderNormalButton
        function OlderNormalButtonPushed(app, event)
                % Slider Preset Values
            
            app.kHzSlider.Value = -10;
            
            app.kHzSlider_2.Value = -10;
           
            app.kHzSlider_3.Value = -11;
            
            app.kHzSlider_4.Value = -15;
           
            app.kHzSlider_5.Value = -20;
           
            app.kHzSlider_6.Value = -20;
            
        end

        % Button pushed function: PreMetabolicButton
        function PreMetabolicButtonPushed(app, event)
                % Slider Preset Values
            
            app.kHzSlider.Value = -10.5;
            
            app.kHzSlider_2.Value = -10.5;
           
            app.kHzSlider_3.Value = -10.5;
            
            app.kHzSlider_4.Value = -13.33;
           
            app.kHzSlider_5.Value = -19.9;
           
            app.kHzSlider_6.Value = -24.4;
            
        end

        % Button pushed function: MetabolicButton
        function MetabolicButtonPushed(app, event)
                % Slider Preset Values
            
            app.kHzSlider.Value = -40;
            
            app.kHzSlider_2.Value = -40;
           
            app.kHzSlider_3.Value = -40;
            
            app.kHzSlider_4.Value = -46.66;
           
            app.kHzSlider_5.Value = -60;
           
            app.kHzSlider_6.Value = -60;
            
        end

        % Button pushed function: SensoryButton
        function SensoryButtonPushed(app, event)
                % Slider Preset Values
            
            app.kHzSlider.Value = -10;
            
            app.kHzSlider_2.Value = -10;
           
            app.kHzSlider_3.Value = -13;
            
            app.kHzSlider_4.Value = -59.5;
           
            app.kHzSlider_5.Value = -86;
           
            app.kHzSlider_6.Value = -90;
            
        end

        % Button pushed function: MetabolicSensoryButton
        function MetabolicSensoryButtonPushed(app, event)
                % Slider Preset Values
            
            app.kHzSlider.Value = -40;
            
            app.kHzSlider_2.Value = -40;
           
            app.kHzSlider_3.Value = -40;
            
            app.kHzSlider_4.Value = -60;
           
            app.kHzSlider_5.Value = -85.2;
           
            app.kHzSlider_6.Value = -90;
            
        end

        % Button pushed function: ZerosButton
        function ZerosButtonPushed(app, event)
                % Slider Preset Values
            
            app.kHzSlider.Value = 0;
            
            app.kHzSlider_2.Value = 0;
           
            app.kHzSlider_3.Value = 0;
            
            app.kHzSlider_4.Value = 0;
           
            app.kHzSlider_5.Value = 0;
           
            app.kHzSlider_6.Value = 0;
            
        end

        % Value changed function: kHzSlider
        function kHzSliderValueChanged(app, event)
                % Function for Extracting the Slider Values:
            
            app.kHzSlider.Value;
            
        end

        % Value changed function: kHzSlider_2
        function kHzSlider_2ValueChanged(app, event)
                % Function for Extracting the Slider Values:
            
            app.kHzSlider_2.Value;
            
        end

        % Value changed function: kHzSlider_3
        function kHzSlider_3ValueChanged(app, event)
                % Function for Extracting the Slider Values:
            
            app.kHzSlider_3.Value;
            
        end

        % Value changed function: kHzSlider_4
        function kHzSlider_4ValueChanged(app, event)
                % Function for Extracting the Slider Values:
            
            app.kHzSlider_4.Value;
            
        end

        % Value changed function: kHzSlider_5
        function kHzSlider_5ValueChanged(app, event)
                % Function for Extracting the Slider Values:
            
            app.kHzSlider_5.Value;
            
        end

        % Value changed function: kHzSlider_6
        function kHzSlider_6ValueChanged(app, event)
                % Function for Extracting the Slider Values:
            
            app.kHzSlider_6.Value;
            
        end

        % Button pushed function: PlotFrequencyversusGainButton
        function PlotFrequencyversusGainButtonPushed(app, event)
                % Plot the Applied Filter:
            
            semilogx (app.UIAxes2, app.fH , 20 * log10 (abs (app.H)) )
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
                % Axes for Frequency versus Gain:
        end

        % Button pushed function: PreviewFilteredAudioButton
        function PreviewFilteredAudioButtonPushed(app, event)
                % Preview the Filtered Audio:
            
            % Define the Inputs for the Filter.
            
            n = 5000; % N implements the frequency response contour.
            
            freqs = [ 250 , 500 , 1000 , 2000 , 4000 , 8000]; % in Hz.
            
            gain_dB = [app.kHzSlider.Value, app.kHzSlider_2.Value, ...
                app.kHzSlider_3.Value, app.kHzSlider_4.Value, ...
                app.kHzSlider_5.Value, app.kHzSlider_6.Value];
            
            fs = app.Fs; % Sampling Frequency.
            
                % Create and Apply Filter:

            [b , app.H , app.fH] = generate_filter (n , freqs ,...
                gain_dB , fs);
                       
            app.Filtered_Audio = apply_filter(app.y , b);
            
            sound (app.Filtered_Audio , app.Fs);


        end

        % Value changed function: PreviewDurationsEditField
        function PreviewDurationsEditFieldValueChanged(app, event)
                % Define Audio Preview Duration:
           
            app.d = app.PreviewDurationsEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Amplitude versus Time')
            xlabel(app.UIAxes, 'Time (s) ')
            ylabel(app.UIAxes, 'Amplitude (m) ')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Gain versus Frequency')
            xlabel(app.UIAxes2, 'Frequency (kHz)')
            ylabel(app.UIAxes2, 'Gain (dB)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.RecordAudioButton = uibutton(app.UIFigure, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.PreviewUploadButton = uibutton(app.UIFigure, 'push');
            app.PreviewUploadButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewUploadButtonPushed, true);
            app.PreviewUploadButton.Text = 'Preview Upload';
            app.UploadAudioButton = uibutton(app.UIFigure, 'push');
            app.UploadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @UploadAudioButtonPushed, true);
            app.UploadAudioButton.Text = 'Upload Audio';
            app.RecondingLengthsEditFieldLabel = uilabel(app.UIFigure);
            app.RecondingLengthsEditFieldLabel.Text = 'Reconding Length (s)';
            app.RecondingLengthsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecondingLengthsEditField.ValueChangedFcn = createCallbackFcn(app, @RecondingLengthsEditFieldValueChanged, true);
            app.RecordingLampLabel = uilabel(app.UIFigure);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.UIFigure);
            app.RecordingLamp.Enable = 'off';
            app.PlotAmplitudeversusTimeButton = uibutton(app.UIFigure, 'push');
            app.PlotAmplitudeversusTimeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAmplitudeversusTimeButtonPushed, true);
            app.PlotAmplitudeversusTimeButton.Text = 'Plot Amplitude versus Time';
            app.OlderNormalButton = uibutton(app.UIFigure, 'push');
            app.OlderNormalButton.ButtonPushedFcn = createCallbackFcn(app, @OlderNormalButtonPushed, true);
            app.OlderNormalButton.Text = 'Older-Normal';
            app.PreMetabolicButton = uibutton(app.UIFigure, 'push');
            app.PreMetabolicButton.ButtonPushedFcn = createCallbackFcn(app, @PreMetabolicButtonPushed, true);
            app.PreMetabolicButton.Text = 'Pre-Metabolic';
            app.MetabolicButton = uibutton(app.UIFigure, 'push');
            app.MetabolicButton.ButtonPushedFcn = createCallbackFcn(app, @MetabolicButtonPushed, true);
            app.MetabolicButton.Text = 'Metabolic';
            app.SensoryButton = uibutton(app.UIFigure, 'push');
            app.SensoryButton.ButtonPushedFcn = createCallbackFcn(app, @SensoryButtonPushed, true);
            app.SensoryButton.Text = 'Sensory';
            app.MetabolicSensoryButton = uibutton(app.UIFigure, 'push');
            app.MetabolicSensoryButton.ButtonPushedFcn = createCallbackFcn(app, @MetabolicSensoryButtonPushed, true);
            app.MetabolicSensoryButton.Text = 'Metabolic + Sensory';
            app.FilterstoApplyLabel = uilabel(app.UIFigure);
            app.FilterstoApplyLabel.Text = 'Filters to Apply:';
            app.kHzSliderLabel = uilabel(app.UIFigure);
            app.kHzSliderLabel.Text = '.25 kHz';
            app.kHzSlider = uislider(app.UIFigure);
            app.kHzSlider.Limits = [-100 0];
            app.kHzSlider.ValueChangedFcn = createCallbackFcn(app, @kHzSliderValueChanged, true);
            app.kHzSlider_2Label = uilabel(app.UIFigure);
            app.kHzSlider_2Label.Text = '.5 kHz';
            app.kHzSlider_2 = uislider(app.UIFigure);
            app.kHzSlider_2.Limits = [-100 0];
            app.kHzSlider_2.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_2ValueChanged, true);
            app.kHzSlider_3Label = uilabel(app.UIFigure);
            app.kHzSlider_3Label.Text = '1 kHz';
            app.kHzSlider_3 = uislider(app.UIFigure);
            app.kHzSlider_3.Limits = [-100 0];
            app.kHzSlider_3.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_3ValueChanged, true);
            app.kHzSlider_4Label = uilabel(app.UIFigure);
            app.kHzSlider_4Label.Text = '2 kHz';
            app.kHzSlider_4 = uislider(app.UIFigure);
            app.kHzSlider_4.Limits = [-100 0];
            app.kHzSlider_4.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_4ValueChanged, true);
            app.kHzSlider_5Label = uilabel(app.UIFigure);
            app.kHzSlider_5Label.Text = '4 kHz';
            app.kHzSlider_5 = uislider(app.UIFigure);
            app.kHzSlider_5.Limits = [-100 0];
            app.kHzSlider_5.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_5ValueChanged, true);
            app.kHzSlider_6Label = uilabel(app.UIFigure);
            app.kHzSlider_6Label.Text = '8 kHz';
            app.kHzSlider_6 = uislider(app.UIFigure);
            app.kHzSlider_6.Limits = [-100 0];
            app.kHzSlider_6.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_6ValueChanged, true);
            app.PlotFrequencyversusGainButton = uibutton(app.UIFigure, 'push');
            app.PlotFrequencyversusGainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFrequencyversusGainButtonPushed, true);
            app.PlotFrequencyversusGainButton.Text = 'Plot Frequency versus Gain';
            app.FilterSlidersforHearingLossLabel = uilabel(app.UIFigure);
            app.FilterSlidersforHearingLossLabel.Text = 'Filter Sliders for Hearing Loss:';
            app.CommandWindowLabel = uilabel(app.UIFigure);
            app.CommandWindowLabel.Text = 'Command Window:';
            app.ZerosButton = uibutton(app.UIFigure, 'push');
            app.ZerosButton.ButtonPushedFcn = createCallbackFcn(app, @ZerosButtonPushed, true);
            app.ZerosButton.Text = 'Zeros';
            app.VedatCanDuzgezenLabel = uilabel(app.UIFigure);
            app.VedatCanDuzgezenLabel.Text = 'Vedat Can Duzgezen';
            app.PreviewFilteredAudioButton = uibutton(app.UIFigure, 'push');
            app.PreviewFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewFilteredAudioButtonPushed, true);
            app.PreviewFilteredAudioButton.Text = 'Preview Filtered Audio';
            app.PreviewDurationsEditFieldLabel = uilabel(app.UIFigure);
            app.PreviewDurationsEditFieldLabel.Text = 'Preview Duration (s)';
            app.PreviewDurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PreviewDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @PreviewDurationsEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2)';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3)';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4)';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '5)';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '6)';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '1)';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '2)';
            app.TorecordaudiofollowBlueBlackPathandRedBlackPathtouploadoneLabel = uilabel(app.UIFigure);
            app.TorecordaudiofollowBlueBlackPathandRedBlackPathtouploadoneLabel.Text = 'Welcome to the app. To record audio follow Blue-Black Path, and Red-Black Path to upload one. Recording audio: Blue Path 1-2, Black Path';
            app.HearingLossSimulationLabel = uilabel(app.UIFigure);
            app.HearingLossSimulationLabel.Text = 'Hearing Loss Simulation';
            app.UploadingAudioRedPath12BlackPath3456Label = uilabel(app.UIFigure);
            app.UploadingAudioRedPath12BlackPath3456Label.Text = '3-4-5-6. Uploading Audio: Red Path 1-2, Black Path 3-4-5-6. ';
            app.YoumayeditthesliderstotheattenuationyouwantLabel = uilabel(app.UIFigure);
            app.YoumayeditthesliderstotheattenuationyouwantLabel.Text = 'You may edit the sliders to the attenuation you want.';
            app.ChooseapresetfiltertoapplyLabel = uilabel(app.UIFigure);
            app.ChooseapresetfiltertoapplyLabel.Text = 'Choose a preset filter to apply.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_App_Bme201P

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end